classdef GenderRecognitionAppGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        StartRecordingButton          matlab.ui.control.Button
        StopRecordingButton           matlab.ui.control.Button
        PlotRecordingButton           matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        PredictGenderButton           matlab.ui.control.Button
        LampLabel                     matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        GenderPredictionbyVoiceLabel  matlab.ui.control.Label
        CalculatedPropertiesLabel     matlab.ui.control.Label
        PredictedGenderLabel          matlab.ui.control.Label
        GenderLabel                   matlab.ui.control.Label
        PlayRecordingButton           matlab.ui.control.Button
        FrequencyLabel                matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        MeanLabel                     matlab.ui.control.Label
        MedianLabel                   matlab.ui.control.Label
        StdDeviationLabel             matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        Label3                        matlab.ui.control.Label
        Label4                        matlab.ui.control.Label
        RemoveSilenceButton           matlab.ui.control.Button
        ClearVoiceButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        recorder; % Description
        playerObj;
        filteredSignal;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            global recObj
               recObj = audiorecorder ; %create object
               record(recObj); %start Recording
               
               % Handle lamp color and lamp label
               app.LampLabel.Text = 'Recording';
               app.Lamp.Color='green'
               
%             app.recorder = audiorecorder
%             record(app.recorder,3)
%             disp('Start speaking.')
%             % app.recorder.StartFcn();
            %https://www.mathworks.com/help/matlab/ref/audiorecorder.html
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            global recObj
                stop(recObj) % Stop
                
                % Handle lamp color and lamp label
                app.StartRecordingButton.Text = 'Restart';
                app.LampLabel.Text = 'Stopped';
                app.Lamp.Color='red'
        end

        % Button pushed function: PlotRecordingButton
        function PlotRecordingButtonPushed(app, event)
                global recObj
                y=getaudiodata(recObj);
                plot(app.UIAxes,y)
        end

        % Button pushed function: PlayRecordingButton
        function PlayRecordingButtonPushed(app, event)
             global recObj;
             global playerObj;
                playerObj = audioplayer(recObj);
                play(playerObj);
        end

        % Button pushed function: RemoveSilenceButton
        function RemoveSilenceButtonPushed(app, event)
            
        end

        % Button pushed function: ClearVoiceButton
        function ClearVoiceButtonPushed(app, event)
          %  global recObj;
          
           % recObj = medfilt1(recObj,90); %Median Filter
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voice Sample')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Icon = 'baseline_fiber_manual_record_black_18dp.png';
            app.StartRecordingButton.Text = {'Start '; 'Recording'};
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Icon = 'baseline_stop_black_18dp.png';
            app.StopRecordingButton.Text = {'Stop '; 'Recording'};
            app.PlotRecordingButton = uibutton(app.UIFigure, 'push');
            app.PlotRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRecordingButtonPushed, true);
            app.PlotRecordingButton.Icon = 'baseline_graphic_eq_black_18dp.png';
            app.PlotRecordingButton.Text = {'Plot'; 'Recording'};
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.Icon = 'baseline_calculate_black_18dp.png';
            app.CalculateButton.Text = 'Calculate';
            app.PredictGenderButton = uibutton(app.UIFigure, 'push');
            app.PredictGenderButton.Icon = 'baseline_supervised_user_circle_black_18dp.png';
            app.PredictGenderButton.Text = {'Predict'; 'Gender'};
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.GenderPredictionbyVoiceLabel = uilabel(app.UIFigure);
            app.GenderPredictionbyVoiceLabel.Text = 'Gender Prediction by Voice';
            app.CalculatedPropertiesLabel = uilabel(app.UIFigure);
            app.CalculatedPropertiesLabel.Text = 'Calculated Properties';
            app.PredictedGenderLabel = uilabel(app.UIFigure);
            app.PredictedGenderLabel.Text = 'Predicted Gender';
            app.GenderLabel = uilabel(app.UIFigure);
            app.GenderLabel.Text = 'GenderLabel';
            app.PlayRecordingButton = uibutton(app.UIFigure, 'push');
            app.PlayRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordingButtonPushed, true);
            app.PlayRecordingButton.Icon = 'baseline_play_arrow_black_18dp.png';
            app.PlayRecordingButton.Text = {'Play '; 'Recording'};
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency:';
            app.Label = uilabel(app.UIFigure);
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'Mean:';
            app.MedianLabel = uilabel(app.UIFigure);
            app.MedianLabel.Text = 'Median:';
            app.StdDeviationLabel = uilabel(app.UIFigure);
            app.StdDeviationLabel.Text = 'Std Deviation:';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            app.RemoveSilenceButton = uibutton(app.UIFigure, 'push');
            app.RemoveSilenceButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSilenceButtonPushed, true);
            app.RemoveSilenceButton.Text = 'Remove Silence';
            app.ClearVoiceButton = uibutton(app.UIFigure, 'push');
            app.ClearVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @ClearVoiceButtonPushed, true);
            app.ClearVoiceButton.Text = 'Clear Voice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenderRecognitionAppGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end